classdef EMG_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        AnalizaeDetajuarTab           matlab.ui.container.Tab
        RezultateteAnalizsRMSLabel    matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        PamjaePergjithshmeTab         matlab.ui.container.Tab
        DropDown                      matlab.ui.control.DropDown
        ZgjidhKanalin18DropDownLabel  matlab.ui.control.Label
        NgarkoSinjalinEMGButton       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NgarkoSinjalinEMGButton
        function NgarkoSinjalinEMGButtonPushed(app, event)

          % 1. Open the file selection window
            [file, path] = uigetfile({'*.mat;*.csv;*.txt'}, 'Zgjidh të dhënat EMG');
            if isequal(file,0), return; end 
            
            % 2. Load the data matrix
            fullPath = fullfile(path, file);
            % We use readmatrix for .txt files with multiple columns
            data = readmatrix(fullPath); 
            
            % 3. CHANNEL SELECTION (Detailed Tab)
            % Get the number from the dropdown (e.g., "Sensor 3" -> 3)
            selectedSensorNum = str2double(extractAfter(app.DropDown.Value, "Sensor "));
            
            % Pick ONLY the column for that sensor
            signal = data(:, selectedSensorNum); 
            
            % 4. PLOT DETAILED TIME DOMAIN
            plot(app.UIAxes, signal); 
            title(app.UIAxes, ['Sinjali: ', file, ' (Sensor ', num2str(selectedSensorNum), ')']);
            xlabel(app.UIAxes, 'Mostrat');
            ylabel(app.UIAxes, 'Amplituda');
            
           % 5. PLOT GENERAL VIEW (All 8 Channels)
            % First, clear any old plots from the tab
            delete(app.PamjaePergjithshmeTab.Children);
            
            % Create the plot and assign it to variable 's'
            s = stackedplot(app.PamjaePergjithshmeTab, data);
            
            % Define the names and apply them to the labels
            sensorNames = {'Sensor 1', 'Sensor 2', 'Sensor 3', 'Sensor 4', ...
                           'Sensor 5', 'Sensor 6', 'Sensor 7', 'Sensor 8'};
            s.DisplayLabels = sensorNames;
        
            % 6. CALCULATE RMS (Interpretation)
            rmsValue = sqrt(mean(signal.^2));
            app.RezultateteAnalizsRMSLabel.Text = ['Rezultatet e Analizës (RMS): ', num2str(rmsValue)];
            
          % 7. CALCULATE FFT (Frequency Domain)
            Fs = 1000; 
            L = length(signal);
            Y = fft(signal); 
            P2 = abs(Y/L);
            P1 = P2(1:floor(L/2)+1); 
            P1(2:end-1) = 2*P1(2:end-1);
            f = Fs*(0:(L/2))/L;
            
            % Plot the FFT - Added 'app.UIAxes2' inside plot to be safe
            plot(app.UIAxes2, f, P1); 
            
            % Explicitly set properties to ensure they aren't wiped
            app.UIAxes2.Title.String = 'Spektri i Frekuencës (FFT)';
            app.UIAxes2.XLabel.String = 'Frekuenca (Hz)';
            app.UIAxes2.YLabel.String = '|P1(f)|';
            grid(app.UIAxes2, 'on');
            
            % Force MATLAB to draw the graphics immediately
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NgarkoSinjalinEMGButton = uibutton(app.UIFigure, 'push');
            app.NgarkoSinjalinEMGButton.ButtonPushedFcn = createCallbackFcn(app, @NgarkoSinjalinEMGButtonPushed, true);
            app.NgarkoSinjalinEMGButton.Text = 'Ngarko Sinjalin EMG';
            app.ZgjidhKanalin18DropDownLabel = uilabel(app.UIFigure);
            app.ZgjidhKanalin18DropDownLabel.Text = 'Zgjidh Kanalin (1-8):';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Sensor 1', 'Sensor 2', 'Sensor 3', 'Sensor 4', 'Sensor 5', 'Sensor 6', 'Sensor 7', 'Sensor 8'};
            app.DropDown.Value = 'Sensor 1';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AnalizaeDetajuarTab = uitab(app.TabGroup);
            app.AnalizaeDetajuarTab.Title = 'Analiza e Detajuar';
            app.UIAxes = uiaxes(app.AnalizaeDetajuarTab);
            title(app.UIAxes, 'Time Domain (Raw Signal)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.AnalizaeDetajuarTab);
            title(app.UIAxes2, 'Frequency Domain (Spektri i Fuqisë)')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.RezultateteAnalizsRMSLabel = uilabel(app.AnalizaeDetajuarTab);
            app.RezultateteAnalizsRMSLabel.Text = 'Rezultatet e Analizës (RMS):';
            app.PamjaePergjithshmeTab = uitab(app.TabGroup);
            app.PamjaePergjithshmeTab.Title = 'Pamja e Përgjithshme';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EMG_Analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end